classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uNnaturalGravityOptionscalculatorUIFigure  matlab.ui.Figure
        gravitationalAcceleration       matlab.ui.control.UIAxes
        tangentialVelocity              matlab.ui.control.UIAxes
        rotationalSpeed                 matlab.ui.control.UIAxes
        OuterRadius                     matlab.ui.control.Spinner
        InnerRadiusLabel                matlab.ui.control.Label
        InnerRadius                     matlab.ui.control.Spinner
        OuterGravityLabel               matlab.ui.control.Label
        OuterGravity                    matlab.ui.control.Spinner
        OuterRadiusLabel                matlab.ui.control.Label
        PeriodsLabel                    matlab.ui.control.Label
        FrequencyHzLabel                matlab.ui.control.Label
        RotationradsLabel               matlab.ui.control.Label
        RotationrpmLabel                matlab.ui.control.Label
        InnertangentialvelocitymsLabel  matlab.ui.control.Label
        OutertangentialvelocitymsLabel  matlab.ui.control.Label
        Innercentripetalaccelerationms2Label  matlab.ui.control.Label
        PERIOD                          matlab.ui.control.Label
        FREQUENCY                       matlab.ui.control.Label
        ROTATION                        matlab.ui.control.Label
        RPM                             matlab.ui.control.Label
        INNERVEL                        matlab.ui.control.Label
        OUTERVEL                        matlab.ui.control.Label
        INNERGRAV                       matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            clear('period');
            clear('frequency');
            clear('angularVelocity');
            clear('rpm');
            clear('innerVelocity');
            clear('outerVelocity');
            clear('centripetalAcceleration');
            cla(app.rotationalSpeed);
            cla(app.tangentialVelocity);
            cla(app.gravitationalAcceleration);
            
            outerRadius = double(app.OuterRadius.Value);
            innerRadius = double(app.InnerRadius.Value);
            outerGravity = double(app.OuterGravity.Value);
            
            period = 2*pi*(sqrt(outerRadius/outerGravity)); % s
            frequency = 1/period; % Hz
            angularVelocity = frequency*2*pi; % rad/s
            rpm = angularVelocity/0.104719755; % rpm
            innerVelocity = (2*pi*innerRadius)/period; % m/s
            outerVelocity = (2*pi*outerRadius)/period; % m/s 
            centripetalAcceleration = (innerVelocity^2)/innerRadius; % m/s^2
            
            syms x
            A = ((1/(2*pi*(sqrt(x/outerGravity))))*2*pi)/0.104719755;
            B = matlabFunction(A);
            C = (((2*pi*x)/period)^2)/x;
            D = matlabFunction(C);
            E = (2*pi*x)/period;
            F = matlabFunction(E);
            x = 0:0.001:outerRadius;
            
            plot(app.rotationalSpeed,x,B(x),'LineWidth',0.5,'Color','blue');
            hold(app.rotationalSpeed);
            plot(app.rotationalSpeed,innerRadius,B(innerRadius),'Marker','o','Color','red');
            plot(app.rotationalSpeed,outerRadius,B(outerRadius),'Marker','o','Color','green');
            hold(app.rotationalSpeed);
            
            plot(app.tangentialVelocity,x,F(x),'LineWidth',0.5,'Color','blue');
            hold(app.tangentialVelocity);
            plot(app.tangentialVelocity,innerRadius,F(innerRadius),'DisplayName','Inner Radius','Marker','o','Color','red');
            plot(app.tangentialVelocity,outerRadius,F(outerRadius),'DisplayName','Outer Radius','Marker','o','Color','green');
            hold(app.tangentialVelocity);
            
            plot(app.gravitationalAcceleration,x,D(x),'LineWidth',0.5,'Color','green')
            hold(app.gravitationalAcceleration);
            plot(app.gravitationalAcceleration,innerRadius,D(innerRadius),'DisplayName','Inner Radius','Marker','o','Color','red');
            plot(app.gravitationalAcceleration,outerRadius,D(outerRadius),'DisplayName','Outer Radius','Marker','o','Color','green');
            hold(app.gravitationalAcceleration);
            
            app.PERIOD.Text = string(period);
            app.FREQUENCY.Text = string(frequency);
            app.ROTATION.Text = string(angularVelocity);
            app.RPM.Text = string(rpm);
            app.INNERVEL.Text = string(innerVelocity);
            app.OUTERVEL.Text = string(outerVelocity);
            app.INNERGRAV.Text = string(centripetalAcceleration);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uNnaturalGravityOptionscalculatorUIFigure = uifigure('Visible', 'off');
            app.uNnaturalGravityOptionscalculatorUIFigure.Name = 'uNnatural Gravity Options calculator';
            app.gravitationalAcceleration = uiaxes(app.uNnaturalGravityOptionscalculatorUIFigure);
            title(app.gravitationalAcceleration, 'Gravitational acceleration at given radius')
            xlabel(app.gravitationalAcceleration, 'Radius (m)')
            ylabel(app.gravitationalAcceleration, 'Centripetal acceleration (m/s^2)')
            app.tangentialVelocity = uiaxes(app.uNnaturalGravityOptionscalculatorUIFigure);
            title(app.tangentialVelocity, 'Tangential velocity at given radius')
            xlabel(app.tangentialVelocity, 'Radius (m)')
            ylabel(app.tangentialVelocity, 'Tangential velocity (m/s)')
            app.rotationalSpeed = uiaxes(app.uNnaturalGravityOptionscalculatorUIFigure);
            title(app.rotationalSpeed, 'Rotation speed needed for earth-like gravity')
            xlabel(app.rotationalSpeed, 'Radius (m)')
            ylabel(app.rotationalSpeed, 'Rotations per minute')
            app.OuterRadius = uispinner(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OuterRadius.Step = 0.1;
            app.OuterRadius.Limits = [0.1 Inf];
            app.OuterRadius.Value = 1;
            app.InnerRadiusLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.InnerRadiusLabel.Text = 'Inner Radius';
            app.InnerRadius = uispinner(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.InnerRadius.Step = 0.1;
            app.InnerRadius.Limits = [0.1 Inf];
            app.InnerRadius.Value = 1;
            app.OuterGravityLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OuterGravityLabel.Text = 'Outer Gravity';
            app.OuterGravity = uispinner(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OuterGravity.Step = 0.1;
            app.OuterGravity.Limits = [0.1 Inf];
            app.OuterGravity.Value = 1;
            app.OuterRadiusLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OuterRadiusLabel.Text = 'Outer Radius';
            app.PeriodsLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.PeriodsLabel.Text = 'Period (s):';
            app.FrequencyHzLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.FrequencyHzLabel.Text = 'Frequency (Hz):';
            app.RotationradsLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.RotationradsLabel.Text = 'Rotation (rad/s):';
            app.RotationrpmLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.RotationrpmLabel.Text = 'Rotation (rpm):';
            app.InnertangentialvelocitymsLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.InnertangentialvelocitymsLabel.Text = 'Inner tangential velocity (m/s):';
            app.OutertangentialvelocitymsLabel = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OutertangentialvelocitymsLabel.Text = 'Outer tangential velocity (m/s):';
            app.Innercentripetalaccelerationms2Label = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.Innercentripetalaccelerationms2Label.Text = 'Inner centripetal acceleration (m/s^2):';
            app.PERIOD = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.PERIOD.Text = ' ';
            app.FREQUENCY = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.FREQUENCY.Text = '';
            app.ROTATION = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.ROTATION.Text = '';
            app.RPM = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.RPM.Text = '';
            app.INNERVEL = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.INNERVEL.Text = '';
            app.OUTERVEL = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.OUTERVEL.Text = '';
            app.INNERGRAV = uilabel(app.uNnaturalGravityOptionscalculatorUIFigure);
            app.INNERGRAV.Text = '';
            app.GoButton = uibutton(app.uNnaturalGravityOptionscalculatorUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.uNnaturalGravityOptionscalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uNnaturalGravityOptionscalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uNnaturalGravityOptionscalculatorUIFigure)
        end
    end
end